classdef Regression_Modeling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MakeNewPredictionButton  matlab.ui.control.Button
        UITable2                 matlab.ui.control.Table
        next                     matlab.ui.control.Button
        previous                 matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        LoadtheDatasetButton     matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        model
        x
        y

        k 

        fullfile
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadtheDatasetButton
        function LoadtheDatasetButtonPushed(app, event)
            [file,path] = uigetfile('.xlsx');
            app.fullfile = strcat(path,file);
            [app.model,app.x,app.y] = RLM(app.fullfile);

            for n = 1: size(app.x,2)
                aux_str = strcat("x",string(n)); 

                columns_names{n} = aux_str; 

            end
            
            columns_names{length(columns_names)+1} = "y"; 

            app.UITable.Data = [app.x,app.y]; 
            app.UITable.ColumnName = columns_names; 

            app.UITable2.ColumnName = columns_names; 
            app.UITable2.Data = zeros(1,size(app.x,2)+1); 


            app.k = 1; 

            plot(app.UIAxes, app.x(:,app.k), app.y, 'o'); 
            
            a = []; 

            for n = 1: size(app.model.Coefficients.Estimate,1)

                a(n) = app.model.Coefficients(n,1).Estimate(1); 

            end 

            a_s = string(a); 
            f = msgbox(a_s); 

        end

        % Button pushed function: previous
        function previousButtonPushed(app, event)


          if app.k > 1
              app.k = app.k - 1; 
              plot(app.UIAxes, app.x(:,app.k), app.y, 'o'); 

              app.UIAxes.XLabel.String =strcat("x",string(app.k)); 
              app.UIAxes.YLabel.String = "y"; 
          end
        end

        % Button pushed function: next
        function nextButtonPushed(app, event)

            if app.k < size(app.x,2)
                app.k = app.k+1; 
                plot(app.UIAxes, app.x(:,app.k), app.y, 'o'); 

                app.UIAxes.XLabel.String =strcat("x",string(app.k)); 
                app.UIAxes.YLabel.String = "y"; 
            end
        end

        % Button pushed function: MakeNewPredictionButton
        function MakeNewPredictionButtonPushed(app, event)
            for n = 1:size(app. x,2)

                data(1,n) = app.UITable2.Data(1,n); 
            end
            app.UITable2.Data(1,size(app.x,2)+1) = predict(app.model,data); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadtheDatasetButton = uibutton(app.UIFigure, 'push');
            app.LoadtheDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtheDatasetButtonPushed, true);
            app.LoadtheDatasetButton.Text = 'Load the Dataset';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.previous = uibutton(app.UIFigure, 'push');
            app.previous.ButtonPushedFcn = createCallbackFcn(app, @previousButtonPushed, true);
            app.previous.Text = '<';
            app.next = uibutton(app.UIFigure, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.next.Text = '>';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.MakeNewPredictionButton = uibutton(app.UIFigure, 'push');
            app.MakeNewPredictionButton.ButtonPushedFcn = createCallbackFcn(app, @MakeNewPredictionButtonPushed, true);
            app.MakeNewPredictionButton.Text = 'Make New Prediction';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Regression_Modeling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end